classdef GRTMOD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        GRTMODAppUIFigure          matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        GRTMOD20May2021contactpierrelanarigeounibechLabel  matlab.ui.control.Label
        Image_InputFile            matlab.ui.control.Image
        InputFileGRTMODintxtLabel  matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        SETDIRECTORYButton         matlab.ui.control.Button
        GridLayout3                matlab.ui.container.GridLayout
        RUNGRTMODButton            matlab.ui.control.Button
        Replacefilelast_GRTMODtxtCheckBox  matlab.ui.control.CheckBox
        OptionsLabel               matlab.ui.control.Label
        GridLayout4                matlab.ui.container.GridLayout
        Image                      matlab.ui.control.Image
        Image_TheriakINI           matlab.ui.control.Image
        TheriakINILabel            matlab.ui.control.Label
    end

    
    properties (Access = private)
        TheriakDirectory    
        PROB               
        RESU    
        VARI
        FIT
        Weights
        WaitBar
    end
    
    methods (Access = private)


        function results = detectInputFiles(app)
            
            if exist([cd,'/GRTMODin.txt'])
                app.Image_InputFile.ImageSource = 'Valid.png';
                app.RUNGRTMODButton.Enable = 'on';
            else
                app.Image_InputFile.ImageSource = 'NotValid.png';
                app.RUNGRTMODButton.Enable = 'off';
            end
            
            if ispc
                if exist([cd,'/theriak.ini'])
                    app.Image_TheriakINI.ImageSource = 'Valid.png';
                    app.RUNGRTMODButton.Enable = 'on';
                else
                    app.Image_TheriakINI.ImageSource = 'NotValid.png';
                    app.RUNGRTMODButton.Enable = 'off';
                end
            else
                app.Image_TheriakINI.Visible = 'off';
                app.TheriakINILabel.Visible = 'off';
            end
            
        end


        function Calc_GRTMOD(app)
            
            app.WaitBar.Message = 'GRTMOD is running numbers: initialization';
            
            clc
            
            disp(' ')
            disp('                               * * *  ');
            disp(' ')
            disp('              -------------------------------------  ');
            disp('               ####  ##### ##### #   # ##### ####   ');
            disp('               #     #   #   #   ## ## #   # #   #  ');
            disp('               # ### #####   #   # # # #   # #   #  ');
            disp('               #  #  # #     #   #   # #   # #   #  ');
            disp('               ####  #  #    #   #   # ##### ####   ');
            disp('              -------------------------------------  ');
            disp(' ')
            disp('                               * * *  ');
            disp(' ')
            disp('                       GRTMOD 2.0 (May 2021)');
            disp('                    pierre.lanari@geo.unibe.ch')
            disp(' ')
            disp(' ')
            disp('- - - - - - - - - - - - - - - - - - - - - - - - - - -')
            disp(['>>> New Run (GRTMODin.txt): ',datestr(now),'  <<<'])
            disp('- - - - - - - - - - - - - - - - - - - - - - - - - - -')
            disp(' ')
            disp(' ')
            
            % - - - Initialization (DO NOT EDIT)  - - - -
            setenv('DYLD_LIBRARY_PATH', '/usr/local/bin')
            setenv('GFORTRAN_STDIN_UNIT', '5')
            setenv('GFORTRAN_STDOUT_UNIT', '6')
            setenv('GFORTRAN_STDERR_UNIT', '0')
            % - - - - - - - - - - - - - - - - - - - - - -
            
            MatlabVersion = version('-release');
            if isequal(MatlabVersion,'2014b') || isequal(MatlabVersion,'2015a') || isequal(MatlabVersion,'2015b') || isequal(MatlabVersion,'2016a')
                SymbolsPlot = {'bo','ro','yo','mo','go','ko','wo','rp','rh','rx'};
            else
                SymbolsPlot = {'bo','go','ro','co','mo','yo','ko','rp','rh','rx'};
            end
            
            figure(1), hold on
            xlabel('Temperature (C)')
            ylabel('Pressure (bar)')
            
            figure(2), hold on
            xlabel('stage')
            ylabel('Garnet Fraction (vol-%)')
            
            
            % RESULT app.VARIABLE INITIALIZATION
            app.RESU(1).NbSol = 0;
            app.RESU(1).SOLS(1).Tfin = [];
            app.RESU(1).SOLS(1).Pfin = [];
            app.RESU(1).SOLS(1).ErrorsT = [];
            app.RESU(1).SOLS(1).ErrorsP = [];
            app.RESU(1).SOLS(1).Res = [];
            app.RESU(1).SOLS(1).GARN(1).VOLU = [];
            app.RESU(1).SOLS(1).GARN(1).DENS = [];
            
            
            % [1] READ GRTMODin.txt and build the app.VARIable app.PROB
            %     New GRTMOD 1.5.1 this function reads Config_GRTMOD as well (24.02.16)
            ReadGRTMODin(app);
             
            disp(['THERIAK directory: ',char(app.PROB.INIT.THER)])
            disp(['Thermodynamic database: ',char(app.PROB.INIT.THDB)])
            disp(' ')
            disp(' ')
            
            dlmwrite('THERCOM',char(char(app.PROB.INIT.THDB),'no'),'delimiter','');
            
            WeHaveSol = 0;
            FirstCOMP = 1;
            
            NbStages = length(app.PROB.STAGE);
            
            for iStage=1:NbStages
                
                disp(' ')
                disp(['################# STAGE ',num2str(iStage),'/',num2str(NbStages),' #################']);
                disp(' ')
                
                if ~app.PROB.STAGE(iStage).COMP
                    
                    if exist(['RESTART_Stage(',num2str(iStage),').mat'])
                        
                        load(['RESTART_Stage(',num2str(iStage),').mat']);
                        app.RESU = RESU;
                        
                        
                        disp(['*** not computed ','RESTART_Stage(',num2str(iStage),').mat',' has been loaded'])
                        disp(' ')
                        
                        if app.PROB.INIT.SELP == 1
                            TheSols2Plot = app.RESU(iStage).BestSol;
                        else
                            TheSols2Plot = 1:app.RESU(iStage).NbSol;
                        end
                        
                        for iRestartSol = 1:length(TheSols2Plot)
                            
                            RestartSol = TheSols2Plot(iRestartSol);
                            
                            disp('     ________________________________________________');
                            disp(['     >> RESTART - SOLUTION (',num2str(RestartSol),'/',num2str(app.RESU(iStage).NbSol),')'])
                            disp(' ')
                            
                            Tfin = app.RESU(iStage).SOLS(RestartSol).Tfin;
                            Pfin = app.RESU(iStage).SOLS(RestartSol).Pfin;
                            ErrorsT = app.RESU(iStage).SOLS(RestartSol).ErrorsT;
                            ErrorsP = app.RESU(iStage).SOLS(RestartSol).ErrorsP;
                            
                            ResSols =  app.RESU(iStage).SOLS(RestartSol).Res;
                            
                            GarnVolStable = app.RESU(iStage).SOLS(RestartSol).GARN.VOLU;
                            ErrorsVol = app.RESU(iStage).SOLS(RestartSol).GARN.ERVO;
                            
                            BulkSols = app.RESU(iStage).SOLS(RestartSol).BULK;
                            
                            disp(['     ',char(BulkSols)])
                            disp(['     T = ',num2str(Tfin),' & P = ',num2str(Pfin)])
                            if isfield(app.RESU(iStage).SOLS(RestartSol),'Cfin')
                                Nb = length(app.RESU(iStage).SOLS(RestartSol).Cfin);
                                if Nb
                                    for iX=1:Nb
                                        disp(['     X-',num2str(iX),' = ',num2str(app.RESU(iStage).SOLS(RestartSol).Cfin(iX))]);
                                    end
                                end
                            end
                            disp(['     Residue = ',num2str(ResSols)])
                            disp(['     GARNET Volume = ',num2str(GarnVolStable),' +/- ',num2str(2*std(ErrorsVol)),' vol-%']);
                            disp(' ')
                            
                            figure(1), hold on
                            for iErr=1:size(ErrorsT,1)
                                plot([Tfin,ErrorsT(iErr)],[Pfin,ErrorsP(iErr)],'-k');
                            end
                            Splot = SymbolsPlot{iStage};
                            plot(Tfin,Pfin,char(Splot),'MarkerFaceColor',Splot(1),'MarkerEdgeColor','k','markersize',7)
                            axis([app.PROB.INIT.TMIN app.PROB.INIT.TMAX app.PROB.INIT.PMIN app.PROB.INIT.PMAX]);
                        end
                        
                        drawnow
                        
                        PlotFigureVolChanges(app,FINA);
                        
                    else
                        disp('     ---- WARNING ----');
                        disp(['     GRTMOD fails to found a restart for the stage: ',num2str(iStage)])
                        disp('     Set the COMP app.VARIable to 1 to compute a new result ...');
                        disp('     -----------------');
                        disp(' ')
                        FctEndGRTMOD(1,FINA);
                        return
                    end
                    
                else % easy, just do it ...
                    
                    % [2] LOOP - OPTIMIZATION
                    
                    if iStage > 1 && ~app.RESU(iStage-1).NbSol
                        disp('     ---- WARNING ----');
                        disp(['     GRTMOD fails to found results for the previous stage: ',num2str(iStage-1)])
                        disp('     Change the tolerance in GRTMODin.txt');
                        disp('     -----------------');
                        disp(' ')
                        FctEndGRTMOD(1,FINA);
                        return
                    end
                    
                    if app.PROB.STAGE(iStage).OPTI
                        
                        disp(['*** New computation (see details below)'])
                        disp(' ')
                        
                        iSols = 0;
                        Tsols = [];
                        Psols = [];
                        ResSols = [];
                        BulkSols = {};
                        GarnVolStable = [];
                        WeHaveSol = 0;
                        
                        iSolsTEMP = 0;
                        TsolsTEMP = [];
                        PsolsTEMP = [];
                        ResSolsTEMP = [];
                        BulkSolsTEMP = {};
                        GarnVolStableTEMP = [];
                        WeHaveSolTEMP = 0;
                        
                        StageINITIALIZATION(app,iStage);
                        
                        % OPTIMIZATION check what we should do ...
                        
                        if length(app.VARI.Names) > 2
                            Optim = [1,1]; % 2 steps optimization
                        else
                            Optim = [1,0];
                        end
                        
                        WeDidOpti2 = 0;
                        
                        for iOpt=1:length(Optim)
                            
                            if WeDidOpti2
                                break
                            end
                            
                            if Optim(iOpt)
                                
                                for i=1:iStage % We define limits
                                    if i<iStage
                                        MinC = app.PROB.STAGE(iStage).MINC(i);
                                    else
                                        MinC=0;
                                    end
                                    
                                    if MinC > 0
                                        LIMS(i,1) = MinC;
                                    else
                                        LIMS(i,1) = app.PROB.GARN(i).FVOL;
                                    end
                                    %LIMS(i,1) = app.PROB.GARN(i).FVOL; % Always the user value as min limit
                                end
                                
                                if iOpt == 1
                                    
                                    InitC = [];
                                    
                                    if app.PROB.STAGE(iStage).OPTI == 2
                                        InitTC = app.PROB.STAGE(iStage).OPTP(1);
                                        InitP = app.PROB.STAGE(iStage).OPTP(2);
                                        iOpt = 2;
                                    else
                                        DeltaT = (app.PROB.INIT.TMAX-app.PROB.INIT.TMIN)/4;
                                        DeltaP = (app.PROB.INIT.PMAX-app.PROB.INIT.PMIN)/4;
                                        
                                        InitTC = [app.PROB.INIT.TMIN+DeltaT,app.PROB.INIT.TMIN+DeltaT,app.PROB.INIT.TMAX-DeltaT,app.PROB.INIT.TMAX-DeltaT];
                                        InitP = [app.PROB.INIT.PMIN+DeltaP,app.PROB.INIT.PMAX-DeltaP,app.PROB.INIT.PMIN+DeltaP,app.PROB.INIT.PMAX-DeltaP];
                                    end
                                    
                                    if iStage == 1
                                        LIMS(1) = app.PROB.GARN(1).FVOL;
                                        LIMS(2) = app.PROB.GARN(1).MVOL;
                                    else
                                        
                                        for i=1:iStage-1
                                            MaxC = app.PROB.STAGE(iStage).MAXC(i);
                                            if MaxC > 0
                                                LIMS(i,2) = MaxC;
                                            else
                                                LIMS(i,2) = FINA.VOLU(i,end);
                                                %app.RESU(i).SOLS((app.RESU(i).BestSol)).GARN.VOLU;
                                            end
                                        end
                                        
                                        % For the new one
                                        if length(app.PROB.GARN(i+1).MVOL)
                                            LIMS(i+1,2) = app.PROB.GARN(i+1).MVOL;
                                        else
                                            LIMS(i+1,2) = 99;
                                        end
                                        %                             if ~length(app.PROB.STAGE(iStage).MAXC)
                                        %                                 if iStage > 2
                                        %                                     NbToBeChanged = iStage-2;
                                        %                                     for iChange = 1:NbToBeChanged
                                        %                                         LIMS(iChange,2) = FINA.VOLU(iChange,end);
                                        %                                     end
                                        %                                 end
                                        %                             end
                                    end
                                    
                                    % Here we optimize the bulk for advanced stages by
                                    % removing the compositions of grt from previous
                                    % core and rims...
                                    if iStage > 1
                                        ADJU.activate = 1;
                                        
                                        % Now taken into account for the first
                                        % optimization...
                                        for i=1:iStage-1
                                            MaxC = app.PROB.STAGE(iStage).MAXC(i);
                                            if MaxC > 0
                                                ADJU.VOLU(i,1) = MaxC;
                                            else
                                                ADJU.VOLU(i,1) = FINA.VOLU(i,end);
                                            end
                                        end
                                        
                                        ADJU.DGAR = FINA.DGAR;
                                        ADJU.DMAT = FINA.DMAT; %(iStage-1)
                                        
                                        ADJU.VSFR = FINA.VSFR(iStage-1);
                                        
                                    else
                                        ADJU.activate = 0;
                                        ADJU.VOLU = [];
                                        
                                        ADJU.DGAR = [];
                                        ADJU.DMAT = [];
                                    end
                                    
                                end
                                
                                if iOpt == 2
                                    
                                    ADJU.activate = 0;
                                    
                                    if app.PROB.STAGE(iStage).OPTI == 1
                                        
                                        for i=1:iStage-1
                                            InitC(i,:) = [LIMS(i,2)-0.000001,LIMS(i,1)+0.000001,(LIMS(i,2)-LIMS(i,1))/2+LIMS(i,1)];
                                        end
                                        
                                        [How,Where] = min(ResSolsTEMP);
                                        
                                        if ~length(Where)
                                            disp(' ')
                                            disp(' ')
                                            disp('    --> NO SOLUTION FROM OPTIMIZATION 1 ...')
                                            FctEndGRTMOD(1);
                                            disp('END')
                                            return
                                        end
                                        
                                        disp('    ...')
                                        disp(['    ==> SOLUTION (',num2str(Where),') is selected for OPTIMIZATION 2  '])
                                        disp(' ')
                                        
                                        InitTC = ones(1,size(InitC,2))*TsolsTEMP(Where);
                                        InitP = ones(1,size(InitC,2))*PsolsTEMP(Where);
                                        
                                    else
                                        for i=1:iStage-1
                                            InitC(i,:) = [(LIMS(i,2)-LIMS(i,1))/2+LIMS(i,1)];
                                        end
                                        
                                        disp(' ')
                                        disp(['    ==> GO FAST MODE (OPTI = 2 # OPTP: ',num2str(InitTC),' - ',num2str(InitP),')'])
                                        disp(' ')
                                    end
                                end
                                
                                % Check if there are additional P,T initial guesses
                                if isfield(app.PROB.STAGE(iStage),'TRYI')
                                    NbAddTry = size(app.PROB.STAGE(iStage).TRYI,1);
                                    
                                    for iAdd=1:NbAddTry
                                        InitTC(end+1) = app.PROB.STAGE(iStage).TRYI(iAdd,1);
                                        InitP(end+1) = app.PROB.STAGE(iStage).TRYI(iAdd,2);
                                        
                                        if iStage > 1 && iOpt == 2
                                            InitC(:,end+1) = InitC(:,end);
                                        end
                                        
                                    end
                                end
                                
                                disp(' ')
                                disp(['    *** OPTIMIZATION ',num2str(iOpt)','']);
                                disp(' ')
                                
                                % display variables for this stage ...
                                fprintf('%s\n','    INPUT VARIABLES --------------------------------------------------------------------------');
                                fprintf('%s\t%6s\t%6s\t%6s\t%6s\t%6s\t%6s\t%6s\t%6s\t%6s\t%6s\n','         ','1','2','3','4','5','6','7','8','9','10');
                                
                                if length(InitTC) <10
                                 	TheSizeNaN = 10-size(InitTC,2);
                                else
                                  	TheSizeNaN = 0;
                                end
                                
                                for i=1:length(app.VARI.Names)
                                    
                                    if i == 1
                                        fprintf('%s\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\n','    TC:  ',[InitTC,zeros(1,TheSizeNaN)]);
                                    elseif i == 2
                                        fprintf('%s\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\t%6.0f\n','    P:   ',[InitP,zeros(1,TheSizeNaN)]);
                                    elseif i > 2 && iOpt == 2 % compositional variables are never refined during iOpt = 1
                                        WhichOne = i-2;
                                        fprintf('%s\t%6.2f\t%6.2f\t%6.2f\t%6.2f\t%6.2f\t%6.2f\t%6.2f\t%6.2f\t%6.2f\t%6.2f\n',['    X',num2str(WhichOne),':   '],[InitC(WhichOne,:),zeros(1,TheSizeNaN)]);
                                    end
                                    
                                end
                                disp(' ');
                                
                                fprintf('%s\n','    LIMITS -------------------');
                                fprintf('%s\t%6s\t%6s\t\n','         ','MIN','MAX');
                                for i=1:size(LIMS,1)
                                    fprintf('%s\t%6.3f\t%6.3f\n',['    V',num2str(i),':   '],LIMS(i,:));
                                end
                                disp(' ');
                                
                                
                                if ADJU.activate
                                    fprintf('%s\n','    ADJUSTEMENTS ------');
                                    fprintf('%s\t%6s\t\n','         ','VOL (%)');
                                    for i=1:length(ADJU.VOLU)
                                        fprintf('%s\t%6.3f\t\n',['    GRT-',num2str(i),':   '],ADJU.VOLU(i));
                                    end
                                    disp(' ');
                                    
                                end
                                
                                Csols = []; % we don't have solution at this point...
                                
                                for iMinim=1:length(InitTC)
                                    
                                    if length(InitC)
                                        X0 = [InitTC(iMinim),InitP(iMinim),InitC(:,iMinim)'];
                                    else
                                        X0 = [InitTC(iMinim),InitP(iMinim)];
                                    end
                                    
                                    app.WaitBar.Message = ['GRTMOD is running numbers: stage ',num2str(iStage),'/',num2str(NbStages),': Optimization ',num2str(iOpt),' (',num2str(iMinim),'/',num2str(length(InitTC)),')'];
                                    
                                    disp('    ________________________________________________');
                                    disp(['    >> RUN ',num2str(iMinim),'/',num2str(length(InitTC)),' ## OPTIMIZATION ',num2str(iOpt),' ## STAGE[',num2str(iStage),'] ## '])
                                    disp(' ')
                                    disp(['    TC0 = ',num2str(X0(1)),' & P0 = ',num2str(X0(2))])
                                    if length(InitC)
                                        for i=1:size(InitC,1)
                                            disp(['    X0-',num2str(i),' = ',num2str(InitC(i,iMinim))]);
                                        end
                                    end
                                    options = optimset('fminsearch'); options=optimset(options,'TolX',app.PROB.INIT.TOLX,'TolFun',app.PROB.INIT.TOLF,'display',app.PROB.INIT.DISP,'MaxFunEvals',300,'MaxIter',100); %, 'LargeScale','off');%,'PlotFcns',@optimplotfval);
                                    
                                    % Scalling of variables
                                    NORM = X0;
                                    X0 = X0./NORM;
                                    
                                    % optimization
                                    tic
                                    f = @WeightOptiStage;
                                    Minimization = 1;
                                    [VarOutOptim, residu] = fminsearch(f, X0,options,app,NORM,app.FIT,app.VARI,app.PROB,InitTC,InitP,app.Weights,LIMS,app.RESU,iOpt,iStage,ADJU,Minimization);
                                    
                                    Minimization = 0;
                                    [residu2,VarOutCompos,VarOutPrint,BulkOut,GarnVolStableOut,Density,FinalVolSystFrac] = WeightOptiStage(VarOutOptim,app,NORM,app.FIT,app.VARI,app.PROB,InitTC,InitP,app.Weights,LIMS,app.RESU,iOpt,iStage,ADJU,Minimization);
                                    
                                    VarOutOptim = VarOutOptim .* NORM;
                                    
                                    if ~length(VarOutPrint)
                                        VarOutPrint = 1e19;
                                    end
                                    TheTime = toc;
                                    disp(' ')
                                    disp(['    ... MINIMIZATION COMPLETED (',num2str(floor(TheTime)),' s)...'])
                                    disp(' ')
                                    
                                    disp(['    ',char(BulkOut)])
                                    disp(['    TC = ',num2str(VarOutOptim(1)),' & P = ',num2str(VarOutOptim(2))])
                                    if length(VarOutOptim) > 2
                                        for i=1:length(VarOutOptim)-2
                                            disp(['    X-',num2str(i),' = ',num2str(VarOutOptim(i+2))]);
                                        end
                                        disp(['    ----']);
                                        
                                    end
                                    if length(VarOutCompos) == 4
                                        disp(['    Composition of GARNET: Alm(',num2str(VarOutCompos(1)),') # Grs(',num2str(VarOutCompos(2)),') # Prp(',num2str(VarOutCompos(3)),') # Sps(',num2str(VarOutCompos(4)),')'])
                                    end
                                    disp(['    Volume fraction of GARNET  = ',num2str(GarnVolStableOut),' (in vol-% of the system)'])
                                    disp(['    Subsystem volume fraction = ',num2str(FinalVolSystFrac*100),' (in vol-% of the system)'])
                                    disp(['    Density of GARNET/MATRIX = ',num2str(Density.GARN),'/',num2str(Density.MATR),' (in g/ccm)'])
                                    disp(['    Residue (Co) = ',num2str(VarOutPrint)])
                                    disp(' ')
                                    
                                    %fprintf('%s\t%6s\t%6s\t%6s\t%6s\n','        ','Alm','Gro','Pyr','Spe');
                                    %fprintf('%s\t%6.3f\t%6.3f\t%6.3f\t%6.3f\n','    GARNET:',VarOutCompos);
                                    
                                    if iOpt == 2
                                        HereWeStop=1;
                                    end
                                    
                                    if iOpt == 1 && Optim(2) == 0 % frist loop (we use app.PROB.INIT.STOL)
                                        
                                        if VarOutPrint < app.PROB.INIT.STOL
                                            % We have a solution
                                            iSols = iSols + 1;
                                            
                                            Tsols(iSols) = VarOutOptim(1);
                                            Psols(iSols) = VarOutOptim(2);
                                            ResSols(iSols) = VarOutPrint;
                                            BulkSols{iSols} = BulkOut;
                                            GarnVolStable(iSols) = GarnVolStableOut;
                                            GarnDenStable(iSols,:) = [Density.GARN,Density.MATR];
                                            VolSystFrac(iSols) = FinalVolSystFrac;
                                            
                                            WeHaveSol = 1;
                                            
                                            disp(['    --> SOLUTION (residue is lower than STOL [',num2str(app.PROB.INIT.STOL),'])'])
                                            disp(' ')
                                        end
                                        
                                        
                                    elseif iOpt == 1 && Optim(2) == 1 && VarOutPrint < 10000 % we take all the solution (to use the best one)
                                        
                                        iSolsTEMP = iSolsTEMP + 1;
                                        
                                        TsolsTEMP(iSolsTEMP) = VarOutOptim(1);
                                        PsolsTEMP(iSolsTEMP) = VarOutOptim(2);
                                        ResSolsTEMP(iSolsTEMP) = VarOutPrint;
                                        BulkSolsTEMP{iSolsTEMP} = BulkOut;
                                        GarnVolStableTEMP(iSolsTEMP) = GarnVolStableOut;
                                        GarnDenStableTEMP(iSolsTEMP,:) = [Density.GARN,Density.MATR];
                                        VolSystFracTEMP(iSolsTEMP) = FinalVolSystFrac;
                                        
                                        WeHaveSolTEMP = 1;
                                        
                                        disp('    --> TEMPORARY SOLUTION (to be used as input for optimization 2)')
                                        disp(' ')
                                    else
                                        if iOpt == 2
                                            WeDidOpti2 = 1;
                                        end
                                        
                                        if VarOutPrint < app.PROB.INIT.STOL
                                            % We have a solution
                                            iSols = iSols + 1;
                                            
                                            Tsols(iSols) = VarOutOptim(1);
                                            Psols(iSols) = VarOutOptim(2);
                                            if length(VarOutOptim) > 2
                                                Csols(iSols,:) = VarOutOptim(3:end)';
                                            end
                                            ResSols(iSols) = VarOutPrint;
                                            BulkSols{iSols} = BulkOut;
                                            GarnVolStable(iSols) = GarnVolStableOut;
                                            GarnDenStable(iSols,:) = [Density.GARN,Density.MATR];
                                            VolSystFrac(iSols) = FinalVolSystFrac;
                                            
                                            WeHaveSol = 1;
                                            
                                            disp(['    --> SOLUTION (residue is lower than STOL [',num2str(app.PROB.INIT.STOL),'])'])
                                            disp(' ')
                                        end
                                        
                                    end
                                end
                            end
                        end
                    end
                    
                    if app.PROB.STAGE(iStage).REFI
                        
                        
                        if WeHaveSol
                            
                            TheSol = 1;
                            
                            CheckSols = zeros(size(Tsols));
                            IndSols = zeros(size(Tsols));
                            Compt = 1;
                            WhereSols = [];
                            
                            for iSol=1:length(Tsols(:))
                                
                                if ~CheckSols(iSol)
                                    
                                    DiffT = abs(Tsols(iSol)-Tsols);
                                    DiffP = abs(Psols(iSol)-Psols);
                                    
                                    DiffT(iSol) = -1;
                                    DiffP(iSol) = -1;
                                    
                                    Where = find(DiffT > 0 & DiffT < app.PROB.INIT.TDI1 & DiffP > 0 & DiffP < app.PROB.INIT.PDI1);
                                    
                                    if Where
                                        CheckSols(iSol) = 1;
                                        CheckSols(Where) = 1;
                                        RefSols(iSol) = Compt;
                                        RefSols(Where) = Compt;
                                        Compt = Compt+1;
                                    else
                                        RefSols(iSol) = Compt;
                                        Compt = Compt+1;
                                    end
                                end
                                
                            end
                        else
                            TheSol = 0;
                        end
                        
                        
                        if TheSol
                            
                            app.RESU(iStage).NbSol = TheSol;
                            
                            NbSol = max(RefSols);
                            app.RESU(iStage).NbSol = NbSol;
                            
                            %figure, hold on
                            
                            for iSol=1:NbSol
                                
                                WhereIsTheSol = find(RefSols == iSol);
                                WhereIsTheSol = WhereIsTheSol(1);
                                
                                if WhereIsTheSol > length(BulkSols)
                                    WhereIsTheSol = iSol; % because there are two solution 1 not taken
                                end
                                
                                app.WaitBar.Message = ['GRTMOD is running numbers: stage ',num2str(iStage),'/',num2str(NbStages),': Auto-refinement ',num2str(WhereIsTheSol),' '];
                                
                                disp('___________________________________________________________________');
                                disp(['>> STAGE[',num2str(iStage),'] ## AUTO-REFINEMENT - SOLUTION (',num2str(iSol),'/',num2str(NbSol),' - Optimization: ',num2str(WhereIsTheSol),')'])
                                disp(' ')
                                disp([char(BulkSols(WhereIsTheSol))])
                                disp(['T = ',num2str(Tsols(WhereIsTheSol)),' & P = ',num2str(Psols(WhereIsTheSol))]);
                                if iStage >1
                                    for i=1:length(Csols(WhereIsTheSol,:))
                                        disp(['X-',num2str(i),' = ',num2str(Csols(WhereIsTheSol,i))]);
                                    end
                                end
                                disp(['Res = ',num2str(ResSols(WhereIsTheSol))])
                                disp(' ')
                                
                                % -> Find the error
                                [Tfin,Pfin,ErrorsT,ErrorsP,ErrorsVol] = FigureOutPTError(app,Tsols(WhereIsTheSol),Psols(WhereIsTheSol),BulkSols(WhereIsTheSol),ResSols(WhereIsTheSol),GarnVolStable(WhereIsTheSol),VolSystFrac(WhereIsTheSol),app.FIT,app.PROB);
                                
                                disp(' ')
                                disp(['Garn Volume = ',num2str(GarnVolStable(WhereIsTheSol)),' +/- ',num2str(2*std(ErrorsVol)),' vol-%']);
                                disp(' ')
                                
                                app.RESU(iStage).SOLS(iSol).BULK = BulkSols(WhereIsTheSol);
                                app.RESU(iStage).SOLS(iSol).Tfin = Tfin;
                                app.RESU(iStage).SOLS(iSol).Pfin = Pfin;
                                app.RESU(iStage).SOLS(iSol).ErrorsT = ErrorsT;
                                app.RESU(iStage).SOLS(iSol).ErrorsP = ErrorsP;
                                app.RESU(iStage).SOLS(iSol).Res = ResSols(WhereIsTheSol);
                                app.RESU(iStage).SOLS(iSol).GARN.VOLU = GarnVolStable(WhereIsTheSol);
                                app.RESU(iStage).SOLS(iSol).GARN.ERVO = ErrorsVol;
                                app.RESU(iStage).SOLS(iSol).DEN.GARN = GarnDenStable(WhereIsTheSol,1);
                                app.RESU(iStage).SOLS(iSol).DEN.BULK = GarnDenStable(WhereIsTheSol,2);
                                app.RESU(iStage).SOLS(iSol).VolSystFrac = VolSystFrac(WhereIsTheSol);
                                
                                if iStage > 1
                                    app.RESU(iStage).SOLS(iSol).Cfin = Csols(WhereIsTheSol,:);
                                end
                                
                            end
                            
                            drawnow
                            
                        else
                            app.RESU(iStage).NbSol = 0;
                            app.RESU(iStage).SOLS = [];
                        end
                        
                        % SELECTION OF THE BEST SOLUTION and PLOTS...
                        
                        app.WaitBar.Message = 'GRTMOD is running numbers: finalizing ...';
                        
                        if app.RESU(iStage).NbSol
                            
                            for i = 1:app.RESU(iStage).NbSol;
                                Res(i) = app.RESU(iStage).SOLS(i).Res;
                                StringChain{i} = ['SOL-',num2str(i),' # Res = ',num2str(Res(i)),' #  TC = ',num2str(app.RESU(iStage).SOLS(i).Tfin),' # P = ',num2str(app.RESU(iStage).SOLS(i).Pfin),' # VOL = ',num2str(app.RESU(iStage).SOLS(i).GARN.VOLU)];
                            end
                            
                            if app.PROB.INIT.SELS == 0
                                [How,Which] = min(Res);
                            else
                                [Which,OK] = listdlg('ListString',StringChain,'SelectionMode','single','ListSize',[400,160],'Name','Select the result');
                                if ~OK
                                    Which =1;
                                end
                            end
                            
                            app.RESU(iStage).BestSol = Which;
                            
                            % PLOT
                            if app.PROB.INIT.SELP == 0
                                LesQuels = 1:app.RESU(iStage).NbSol; % All
                            else
                                LesQuels = app.RESU(iStage).BestSol; % The best one...
                            end
                            
                            figure(1), hold on
                            for iP=1:length(LesQuels)
                                iSol = LesQuels(iP);
                                for iErr=1:size(app.RESU(iStage).SOLS(iSol).ErrorsT,1)
                                    plot([app.RESU(iStage).SOLS(iSol).Tfin,app.RESU(iStage).SOLS(iSol).ErrorsT(iErr)],[app.RESU(iStage).SOLS(iSol).Pfin,app.RESU(iStage).SOLS(iSol).ErrorsP(iErr)],'-k');
                                end
                                
                                Splot = SymbolsPlot{iStage};
                                plot(app.RESU(iStage).SOLS(iSol).Tfin,app.RESU(iStage).SOLS(iSol).Pfin,char(Splot),'MarkerFaceColor',Splot(1),'MarkerEdgeColor','k','markersize',7)
                                axis([app.PROB.INIT.TMIN app.PROB.INIT.TMAX app.PROB.INIT.PMIN app.PROB.INIT.PMAX]);
                            end
                            
                            drawnow
                            
                            % SAVE FINAL DISPLAY app.VARIABLE
                            FINA.VOLU(iStage,iStage) = app.RESU(iStage).SOLS(app.RESU(iStage).BestSol).GARN.VOLU;
                            FINA.ERVO(iStage,iStage) = 2*std(app.RESU(iStage).SOLS(app.RESU(iStage).BestSol).GARN.ERVO);
                            
                            FINA.DGAR(iStage) = app.RESU(iStage).SOLS(app.RESU(iStage).BestSol).DEN.GARN;
                            FINA.DMAT(iStage) = app.RESU(iStage).SOLS(app.RESU(iStage).BestSol).DEN.BULK;
                            
                            FINA.VSFR(iStage) = app.RESU(iStage).SOLS(app.RESU(iStage).BestSol).VolSystFrac;
                            
                            if iStage > 1
                                % update the optimized rims
                                TheCfin = app.RESU(iStage).SOLS(app.RESU(iStage).BestSol).Cfin;
                                for iC = 1:length(TheCfin);
                                    FINA.VOLU(iC,iStage) = TheCfin(iC);
                                    FINA.ERVO(iC,iStage) = 0;
                                end
                            end
                            
                            app.WaitBar.Message = 'GRTMOD is running numbers: plotting results ...';
                            
                            PlotFigureVolChanges(app,FINA);
                            
                            drawnow
                            
                            % SAVE RESTART
                            RESU = app.RESU;
                            save(['RESTART_Stage(',num2str(iStage),').mat'],'RESU','FINA');
                        else
                            disp(' ');
                            disp(' ');
                            disp('    --> NO SOLUTION FOR THIS STAGE (increase STOL)...')
                            
                        end
                        
                    end
                end


                if app.PROB.STAGE(iStage).MAPC % Work for stage 1
                    
                    disp(['*** New MAP (see details below)'])
                    disp(' ')
                    
                    StageINITIALIZATION(app,iStage);;
                    
                    if isequal(app.PROB.STAGE(iStage).MAPC,1)
                        TheT = app.PROB.INIT.TMIN:app.PROB.INIT.TDI1:app.PROB.INIT.TMAX;
                        TheP = app.PROB.INIT.PMIN:app.PROB.INIT.PDI1:app.PROB.INIT.PMAX;
                    else
                        TheT = app.PROB.INIT.TMIN:app.PROB.INIT.TDI2:app.PROB.INIT.TMAX;
                        TheP = app.PROB.INIT.PMIN:app.PROB.INIT.PDI2:app.PROB.INIT.PMAX;
                    end
                    
                    DeltaT = (app.PROB.INIT.TMAX-app.PROB.INIT.TMIN)/4;
                    DeltaP = (app.PROB.INIT.PMAX-app.PROB.INIT.PMIN)/4;
                    
                    InitTC = [app.PROB.INIT.TMIN+DeltaT,app.PROB.INIT.TMIN+DeltaT,app.PROB.INIT.TMAX-DeltaT,app.PROB.INIT.TMAX-DeltaT];
                    InitP = [app.PROB.INIT.PMIN+DeltaP,app.PROB.INIT.PMAX-DeltaP,app.PROB.INIT.PMIN+DeltaP,app.PROB.INIT.PMAX-DeltaP];
                    
                    if iStage == 1
                        LIMS(1) = 0;  %app.PROB.GARN(iStage).FVOL;
                        LIMS(2) = 99; %app.PROB.GARN(iStage).MVOL;
                        % Or app.PROBlems with the minimum value ...
                    else
                        
                        for i=1:iStage-1
                            MaxC = app.PROB.STAGE(iStage).MAXC(i);
                            if MaxC > 0
                                LIMS(i,2) = MaxC;
                            else
                                LIMS(i,2) = FINA.VOLU(i,iStage-1);
                                %app.RESU(i).SOLS((app.RESU(i).BestSol)).GARN.VOLU;
                            end
                            
                            NameGrt{i} = ['X-',num2str(i)];
                            DefGrt{i} = num2str(LIMS(i,2)-0.01);
                        end
                        
                        % For the new one
                        if length(app.PROB.GARN(i+1).MVOL)
                            LIMS(i+1,2) = app.PROB.GARN(i+1).MVOL;
                        else
                            LIMS(i+1,2) = 99;
                        end
                        
                        app.PROB.GARN(1+i).FVOL; % Always the user value
                        
                        Answer = inputdlg(NameGrt,'Fractionation',1,DefGrt);
                        
                        if ~length(Answer)
                            return
                        end
                        
                        for i=1:length(Answer)
                            
                            AddX0(i) = str2num(Answer{i});
                        end
                        
                    end
                    
                    % Here we optimize the bulk for advanced stages by
                    % removing the compositions of grt from previous
                    % core and rims...
                    if iStage > 1
                        ADJU.activate = 1;
                        ADJU.VOLU = FINA.VOLU(:,iStage-1);
                        
                        ADJU.DGAR = FINA.DGAR;
                        ADJU.DMAT = FINA.DMAT; %(iStage-1)
                        
                        ADJU.VSFR = FINA.VSFR(iStage-1);
                        
                    else
                        ADJU.activate = 0;
                        ADJU.VOLU = [];
                        
                        ADJU.DGAR = [];
                        ADJU.DMAT = [];
                    end
                    
                    if iStage > 1
                        iOpt = 2;
                    else
                        iOpt = 1;
                    end
                    
                    VarOutPrint = zeros(length(TheP),length(TheT));
                    GarnVolStableOut = zeros(length(TheP),length(TheT));
                    residu2 = zeros(length(TheP),length(TheT));
                    
                    h = waitbar(0,'Please wait GRTMOD is running numbers ...');
                    
                    for iT = 1:length(TheT)
                        waitbar(iT/length(TheT),h);
                        for iP = 1:length(TheP)
                            
                            X0 = [TheT(iT),TheP(iP)];
                            if iStage>1
                                X0(end+1:end+length(AddX0)) = AddX0;
                            end
                            
                            NORM = X0;
                            X0 = X0./NORM;
                            
                            [residu2(iP,iT),VarOutCompos,VarOutPrint(iP,iT),BulkOut,GarnVolStableOut(iP,iT),Density,FinalVolSystFrac] = WeightOptiStage(X0,app,NORM,app.FIT,app.VARI,app.PROB,InitTC,InitP,app.Weights,LIMS,app.RESU,iOpt,iStage,ADJU,2);
                            
                            if VarOutPrint(iP,iT) > 2000 && GarnVolStableOut(iP,iT)
                                disp('This should never happend (GES-197)! Please contact P.Lanari')
                                keyboard
                            end
                        end
                        
                        %save MAP_TEMP.mat
                    end
                    
                    close(h)
                    
                    %save('MAP_FINAL.mat','MAP_FINAL');
                    
                    VarOutPrint2 = VarOutPrint;
                    ValueMax = max(VarOutPrint2(VarOutPrint2 < 100));
                    ValueThresold = ValueMax + 0.2;
                    VarOutPrint2(VarOutPrint2 > 100) = ValueThresold*ones(size(VarOutPrint2(VarOutPrint2 > 100)));
                    VarOutPrint2(VarOutPrint2(:) >= 1) = nan.*zeros(size(VarOutPrint2(VarOutPrint2(:) >= 1)));
                    
                    figure
                    imagesc(TheT,TheP/1000,VarOutPrint2), colorbar
                    
                    caxis([0,ValueMax+0.05])
                    %colormap([jet(64);0,0,0])
                    set(gca,'YDir','normal')
                    colormap([jet(64);0,0,0])
                    title('C0 parameter')
                    
                    
                    figure
                    imagesc(TheT,TheP/1000,residu2), colorbar
                    
                    %caxis([0,0.2])
                    %colormap([jet(64);0,0,0])
                    set(gca,'YDir','normal')
                    colormap([.5,.5,.5;jet(64)])
                    title('Obj Function (weighted)')
                    
                    
                    figure
                    imagesc(TheT,TheP/1000,GarnVolStableOut), colorbar
                    
                    %caxis([0,0.2])
                    %colormap([jet(64);0,0,0])
                    set(gca,'YDir','normal')
                    colormap([.5,.5,.5;jet(64)])
                    title('Volume of garnet stable')
                    
                    % Replace not stable by not a number !
                    VarOutPrint3 = VarOutPrint;
                    VarOutPrint3(VarOutPrint3(:) >= 1) = nan.*zeros(size(VarOutPrint3(VarOutPrint3(:) >= 1)));
                    
                    figure, hold on
                    surf(TheT,TheP/1000,VarOutPrint3,'EdgeColor','k'), colorbar
                    colormap(jet)
                    title('app.FIT parameter')
                    colorbar
                    grid on
                    
                    %contour3(TheT,TheP/1000,VarOutPrint3,'k')
                    %,[0:0.01:0.5],'k'
                    
                    
                    figure, hold on
                    surf(TheT,TheP/1000,GarnVolStableOut,'EdgeColor','k'), colorbar
                    colormap(jet)
                    title('Volume of garnet stable')
                    colorbar
                    grid on
                    
                    %contour3(TheT,TheP/1000,GarnVolStableOut,'k')
                    
                    
                    save MAP_FINAL
                    %keyboard
                    
                    
                end
                
            end
            
            
            if iStage > 1
                FctEndGRTMOD(app,1,FINA);
            else
                if app.RESU(iStage).NbSol
                    FctEndGRTMOD(app,0,FINA);
                end
            end
            
            disp('END')
        end


        function [] = FctEndGRTMOD(app,varin,FINA)
            
            if varin
                
                VolTable = FINA.VOLU';
                ResTable = zeros(size(VolTable));  % Resorped garnet
                NGrTable = zeros(size(VolTable));  % Newly grown grt
                NbStage = size(VolTable,1);
                
                PrintCode1 = '%s\t\t';
                PrintCode2 = '%s\t\t';
                GrtLabels{1} = '    ';
                
                for iS=1:NbStage
                    NGrTable(iS,iS) = VolTable(iS,iS);
                    
                    if iS > 1
                        for j = 1:iS
                            if VolTable(iS-1,j)
                                ResTable(iS,j) = VolTable(iS-1,j)-VolTable(iS,j);
                            end
                        end
                    end
                    
                    NGarnetGrowth = VolTable(iS,iS)/100;
                    NMatrix = 1-(sum(VolTable(iS,:))/100);
                    
                    DensityRock(iS) = FINA.DGAR(iS)*NGarnetGrowth + FINA.DMAT(iS)*NMatrix;
                    if iS > 1   % we should take into account the previous garnet
                        for iGrt = 1:iS-1
                            DensityRock(iS) = DensityRock(iS) + FINA.DGAR(iGrt)*VolTable(iS,iGrt)/100;
                        end
                    end
                    
                    StageLabels{iS} = ['Stage ',num2str(iS)];
                    GrtLabels{iS+1} = ['Grt',num2str(iS)];
                    PrintCode1 = [PrintCode1,'%.3f\t'];
                    PrintCode2 = [PrintCode2,'%s\t'];
                end
                
                GrtLabels{iS+2} = 'Total';
                
                PrintCode1 = [PrintCode1,'%.3f\n'];
                PrintCode2 = [PrintCode2,'%s\n'];
                
                disp(' ')
                disp(' ')
                disp(' ')
                disp(' -----------------------------------')
                disp('|           FINAL RESULTS           |')
                disp(' -----------------------------------')
                disp(' ')
                disp(' -> GARNET RESORPTION (vol-%)');
                fprintf(PrintCode2,GrtLabels{:});
                for i=1:NbStage
                    fprintf(PrintCode1,StageLabels{i},ResTable(i,:),sum(ResTable(i,:)));
                end
                
                disp(' ')
                disp(' -> VOLUME OF GARNET (vol-%)');
                fprintf(PrintCode2,GrtLabels{:});
                for i=1:NbStage
                    fprintf(PrintCode1,StageLabels{i},VolTable(i,:),sum(VolTable(i,:)));
                end
                
                disp(' ')
                disp(' -> NEWLY GROWN GARNET (vol-%)');
                fprintf(PrintCode2,GrtLabels{:});
                for i=1:NbStage
                    fprintf(PrintCode1,StageLabels{i},NGrTable(i,:),sum(NGrTable(i,:)));
                end
                
                disp(' ')
                disp(' -> ROCK DENSITY (g/ccm)');
                fprintf('%s\t\t%s\n','    ','Density');
                for i=1:NbStage
                    fprintf('%s\t\t%.3f\n',StageLabels{i},DensityRock(i));
                end
            end
            
            if exist('THERCOM','file')
                delete THERCOM
            end
            
            disp(' ')
            disp(' ')
            disp(' ')
            disp(' ')
            disp('- - - - - - - - - - - - - - - - - - - - - - - - - - -')
            disp(['>>> WORK DONE: ',datestr(now),'  <<<'])
            disp('- - - - - - - - - - - - - - - - - - - - - - - - - - -')
            disp(' ')
            disp(' ')
        end


        function StageINITIALIZATION(app,iStage)
            
            % [2.1] Set variables for this stage
            app.VARI.Names = {'TC','P'};
            
            NbCompVariables = iStage-1;
            for iV=1:NbCompVariables
                app.VARI.Names{2+iV} = [char(app.PROB.STAGE(iStage).CVAR.LIST(iV)),'-',char(num2str(app.PROB.STAGE(iStage).CVAR.IND(iV)))];
            end
            
            % [2.2] Calculate GARNET end-member proportions of the stable garnet
            app.FIT = StructFormEMP(app,app.PROB.GARN(iStage).COMP); % Garnet defined at this stage (app.FIT = [XAlm,XGro,XPyr,XSpe];)
            app.Weights = app.PROB.GARN(iStage).WEIG;
            
            fprintf('%s\n','    PARAMETERS -------------------------------');
            fprintf('%s\t%6s\t%6s\t%6s\t%6s\n','        ','Alm','Grs','Prp','Sps');
            fprintf('%s\t%6.3f\t%6.3f\t%6.3f\t%6.3f\n','    FIT:',app.FIT);
            fprintf('%s\t%6.0f\t%6.0f\t%6.0f\t%6.0f\n','    WEIGHT:',app.Weights);
            disp(' ');
            
        end


        function [EMP] = StructFormEMP(app,InputOXC)
            
            NbOx = 12; % Garnet...
            
            % SiO2 / TIO2 / Al2O3 / FeO / Fe2O3 / MnO / MgO / CaO / Na2O / K2O
            Num = [1,1,2,1,2,1,1,1,2,2]; % Nombre de cations.
            NumO= [2,2,3,1,3,1,1,1,1,1]; % Nombre d'Oxygenes.
            Cst = [60.09,79.88,101.96,71.85,159.68,70.94,40.30,56.08, ...
                61.98,94.20]; % atomic mass
            
            TravMat = InputOXC';
            %
            % TravMat = []; % initialization required...
            %
            % TravMat(1:4) = Analyse(1:4); % Si02 TiO2 Al2O3 FeO
            % TravMat(5) = 0; % Fe2O3
            % TravMat(6:10) = Analyse(5:9); % MnO MgO CaO Na2O K2O
            
            AtomicPer = TravMat./Cst.*Num;
            
            TheSum = sum((AtomicPer .* NumO) ./ Num);
            RefOx = TheSum/NbOx;
            
            lesResults = AtomicPer / RefOx;
            
            Si = lesResults(1);
            Ti = lesResults(2);
            Al = lesResults(3);
            Fe = lesResults(4)+ lesResults(5);
            Mn = lesResults(6);
            Mg = lesResults(7);
            Ca = lesResults(8);
            Na = lesResults(9);
            K = lesResults(10);
            
            XGro = Ca/(Ca+Fe+Mg+Mn);
            XPyr = Mg/(Ca+Fe+Mg+Mn);
            XAlm = Fe/(Ca+Fe+Mg+Mn);
            XSpe = Mn/(Ca+Fe+Mg+Mn);
            
            % Alm   Gro   Pyr
            %FIT = [0.606 0.182 0.212];
            
            EMP = [XAlm,XGro,XPyr,XSpe];
            
        end


        function [VarOutOptim,VarOutCompos,VarOutPrint,BulkOut,GarnVolStableOut,Density,FinalVolSystFrac] = WeightOptiStage(X0,app,NORM,FIT,VARI,PROB,InitTC,InitP,Weights,LIMS,RESU,Opti,iStage,ADJU,Minimization)
            
            VarOutOptim=[];
            VarOutCompos=[];
            VarOutPrint=[];
            Density.GARN = 0;
            Density.MATR = 0;
            FinalVolSystFrac = [];
            
            BulkOut = '';
            GarnVolStableOut = [];
            
            % Very important: scaling of the variables
            X0 = X0.*NORM;
            
            D_Temp = num2str(X0(1));
            D_Press = num2str(X0(2));
            
            if length(X0) > 2
                XVolOptimizing = X0(3:end);
                
                for i=1:length(XVolOptimizing)
                    if XVolOptimizing(i) < LIMS(i,1) || XVolOptimizing(i) > LIMS(i,2)
                        VarOutOptim = 1e19;
                        VarOutCompos = [];
                        %keyboard
                        return
                    end
                end
            end
            
            if X0(1) < PROB.INIT.TMIN || X0(1) > PROB.INIT.TMAX || X0(2) < PROB.INIT.PMIN || X0(2) > PROB.INIT.PMAX
                %X0(1) <= InitTC(1) || X0(1) >= InitTC(end) || X0(2) <= InitP(1) || X0(2) >= InitP(2)
                VarOutOptim = 1e19;
                VarOutCompos = [];
                %keyboard
                return
            end
            
            TheCompo = PROB.INIT.BULK';
            
            if iStage > 1
                % we update the effective bulk composition
                
                NbGarnetToConsider = iStage-1;
                
                for i=1:NbGarnetToConsider
                    ComposGrt(i,:) = PROB.GARN(i).COMP';
                    
                    %         if i==1
                    %             VolSystFrac(i) = 1;
                    %         else
                    %             VolSystFrac(i) = 1-sum(VolFractionsGrt(1:i-1));
                    %         end
                    
                    if isequal(Opti,1)
                        VolFractionsGrt(i) = ADJU.VOLU(i)/100; % This is corrected
                        %VolFractionsGrt(i) = UncorrectedVolFractionsGrt(i) * VolSystFrac(i);
                    else
                        VolFractionsGrt(i) = XVolOptimizing(i)/100; % This is corrected before
                        %VolFractionsGrt(i) = UncorrectedVolFractionsGrt(i) * VolSystFrac(i);
                    end
                    
                    RoGrt(i) = ADJU.DGAR(i);
                    
                end
                
                RoMtx = ADJU.DMAT(NbGarnetToConsider); % The last one (the last matrix)
                RoMixt = sum(VolFractionsGrt.*RoGrt)+(1-sum(VolFractionsGrt))*RoMtx;
                
                for i=1:NbGarnetToConsider
                    
                    RoGrtRoMixt = RoGrt(i)/RoMixt;
                    RoMtxRoMixt = RoMtx/RoMixt;
                    
                    nGarnet = VolFractionsGrt(i);
                    OxideGarnet2Remov = ComposGrt(i,:);
                    
                    TheCompo = (TheCompo-nGarnet*RoGrtRoMixt*OxideGarnet2Remov)./(RoMtxRoMixt*(1-nGarnet));
                    
                    TheCompo(find(TheCompo<0)) = zeros(size(find(TheCompo<0)));
                end
            else
                TheCompo = PROB.INIT.BULK';
            end
            
            %SIO2 TIO2   AL2O3  FeO  Fe2O3 MNO MgO   CaO   Na2O  K2O
            TheStructForm = TheCompo./[60.09,77.866,101.96,71.85,159.68,70.94,40.3,56.08,61.98,94.2].*[1,1,2,1,2,1,1,1,2,2];
            
            InvMet.path = char(PROB.INIT.THER);
            InvMet.dbs=char(PROB.INIT.THDB);
            
            % GRTMOD 1.6
            if isfield(PROB.INIT,'NOFI')
                OxygenVal =  char(PROB.INIT.NOFI);
            else
                OxygenVal = '?';
            end
            if isfield(PROB.INIT,'NCFI')
                CarbonVal =  PROB.INIT.NCFI;
            else
                CarbonVal = 0;
            end
            
            InvMet.Bulk2Display = ['SI(',num2str(TheStructForm(1)),')AL(',num2str(TheStructForm(3)),')FE(',num2str(TheStructForm(4)),')MN(',num2str(TheStructForm(6)),')MG(',num2str(TheStructForm(7)),')CA(',num2str(TheStructForm(8)),')NA(',num2str(TheStructForm(9)),')TI(',num2str(TheStructForm(2)),')K(',num2str(TheStructForm(10)),')H(',num2str(PROB.INIT.NH2O),')O(',OxygenVal,')C(',num2str(CarbonVal),')O(',num2str(PROB.INIT.NEXO),')    ',char(PROB.INIT.SAMP)];
            InvMet.In = 'in';
            
            InvMet.bcomp ='THERIN';
            
            [GarnVolStableOut,XAlm,XGro,XPyr,XSpe,Dgrt,Dsys] = Call4Theriak(app,InvMet,D_Temp,D_Press);
            
            % Correction of GarnVolStableOut:
            if iStage > 1
                FinalVolSystFrac = 1-sum(VolFractionsGrt); % = ADJU.VSFR - VolFractionsGrt(end);
            else
                FinalVolSystFrac = 1;
            end
            GarnVolStable = GarnVolStableOut;
            GarnVolStableOut = GarnVolStableOut*FinalVolSystFrac;  % In total system unit
            
            % Here I used LIMS(end,1) and LIMS(end,2) because we stabilized the garnet
            % of this stage...
            if GarnVolStableOut && GarnVolStableOut >= LIMS(end,1) && GarnVolStableOut <= LIMS(end,2)
                
                %VarOutOptim = sqrt(sum(((FIT - [XAlm,XGro,XPyr,XSpe])./Weights').^2));
                
                % Corrected PL 19.03.2018 (to fit with the equation 12 in the paper.
                VarOutOptim = sqrt(sum(((FIT - [XAlm,XGro,XPyr,XSpe]).^2)./Weights'));
                
                VarOutPrint = sqrt(sum(((FIT - [XAlm,XGro,XPyr,XSpe])).^2));
                %VarOutPrint = sum(abs(FIT-[XAlm,XGro,XPyr,XSpe]));
                %VarOutOptim = VarOutPrint;
                VarOutCompos = [XAlm,XGro,XPyr,XSpe];
                
                BulkOut = InvMet.Bulk2Display;
                
            else
                VarOutOptim = 1e19;
                VarOutPrint = 1e19;
                VarOutCompos = [];
                
                BulkOut = '';
            end
            
            if isequal(Minimization,0)
                % (1) Rock-density
                DensBulk = Dsys;
                
                % (1) Garnet specific density (not yet implementwed)...
                if GarnVolStableOut
                    Density.GARN = Dgrt;
                else
                    Density.GARN = 0;
                end
                
                % use GarnVolStable because unscalled...
                Density.MATR = (DensBulk - GarnVolStable/100*Density.GARN)/(1-GarnVolStable/100);
            end
            
        end
 

        function [VolPerGar,XAlm,XGro,XPyr,XSpe,Dgrt,Dsys] = Call4Theriak(app,InvMet,D_Temp,D_Press)
            %
            %
            %
            %
            INFILE=char( [D_Temp,'   ',D_Press],['1   ',InvMet.Bulk2Display]);
            dlmwrite(InvMet.bcomp , INFILE,'delimiter','');
            
            [wum,yum]=system([[InvMet.path,'/theriak'],'   THERCOM   THERIN']);
            
            [GrtResult] = ReadResTheriakGrtMod(app,yum);
            
            VolPerGar = GrtResult(1);
            XAlm = GrtResult(2);
            XGro = GrtResult(3);
            XPyr = GrtResult(4);
            XSpe = GrtResult(5);
            Dgrt = GrtResult(6);
            Dsys = GrtResult(7);
            
        end


        function [GrtResult] = ReadResTheriakGrtMod(app,OutputTheriakd)
            %
            %
            % GrtResult = [IsGrt,Dgrt,Dsyst,XAlm,XGro,XPyr,XSpe
            
            GrtResult = zeros(1,7);
            
            TestInput = strread(OutputTheriakd,'%s','delimiter','\n');
            
            %
            WhereElOrder = find(ismember(TestInput,'elements in stable phases:'))+3;
            
            if isempty(WhereElOrder)
                OutputTheriakd
                error('ERROR WITH THE DATABASE (see theriak output above) ...');
                return
            end
            
            El1 = strread(char(TestInput(WhereElOrder)),'%s')';
            if length(El1) == 10
                SecondRow = strread(char(TestInput(WhereElOrder+1)),'%s')';
                if length(SecondRow) < length(El1)
                    El1 = [El1,SecondRow];
                end
            end
            
            WorkVariMod(1).Els = El1;
            WorkVariMod(1).NbEl = length(El1);
            
            if WorkVariMod(1).NbEl > 10
                Shift = 1;
            else
                Shift = 0;
            end
            
            % The code below is only compatible with MAC users...
            % --------------------------------------------------------------- S T A R T
            % WhereCompositions = find(ismember(TestInput,'elements per formula unit:'))+1;
            % Compt = 1; Indice = 1;
            % while 1
            %     ShiftRows = (Compt-1)*Shift;
            %     Temp = strread(char(TestInput(WhereCompositions+Compt+ShiftRows)),'%s')';
            %     if isempty(Temp)
            %         break
            %     end
            %     if Shift
            %         Temp2 = strread(char(TestInput(WhereCompositions+Compt+ShiftRows+1)),'%s')';
            %     end
            %
            %     NbElem1 = length(str2double(Temp(2:end)));
            %     ASS_Indice(Indice) = Compt;
            %     ASS_Names{Indice} = Temp{1};
            %     ASS_COMP(Indice,1:NbElem1) = str2double(Temp(2:end));
            %     if Shift
            %         ASS_COMP(Indice,NbElem1+1:NbElem1+length(Temp2)) = str2double(Temp2);
            %     end
            %     Indice = Indice+1;
            %
            %     Compt = Compt+1;
            % end
            % ------------------------------------------------------------------- E N D
            
            % For all users ...
            
            WhereCompositions = find(ismember(TestInput,'elements in stable phases:'))+3+Shift;
            Compt = 1; Indice = 1;
            while 1
                ShiftRows = (Compt-1)*Shift;
                Temp = strread(char(TestInput(WhereCompositions+Compt+ShiftRows)),'%s')';
                if isempty(Temp)
                    break
                end
                if Shift
                    Temp2 = strread(char(TestInput(WhereCompositions+Compt+ShiftRows+1)),'%s')';
                end
                
                NbElem1 = length(str2double(Temp(2:end)));
                ASS_Indice2(Indice) = Compt;
                ASS_Names2{Indice} = Temp{1};
                ASS_COMP2(Indice,1:NbElem1) = str2double(Temp(2:end));
                if Shift
                    ASS_COMP2(Indice,NbElem1+1:NbElem1+length(Temp2)) = str2double(Temp2);
                end
                Indice = Indice+1;
                
                Compt = Compt+1;
            end
            
            %
            WhereVol = find(ismember(TestInput,'volumes and densities of stable phases:'))+4;
            
            Compt = 1; Indice = 1;
            while 1
                Temp = strread(char(TestInput(WhereVol+Compt)),'%s')';
                
                if ~isequal(length(Temp),11)
                    break
                end
                
                VOL_VolFrac(Compt) = str2double(Temp(5))/100;
            	VOL_Dens(Compt) = str2double(Temp(11));
                
                VOL_Names{Compt} = Temp{1};
                
                Compt = Compt+1;
            end
            
            Temp = strread(char(TestInput(WhereVol+Compt+1)),'%s')';
            GrtResult(7) = str2double(Temp{end});
            
            % Check Theriak Names and remove EM abbreviations (MAC)
            % --------------------------------------------------------------- S T A R T
            % for i=1:length(ASS_Names)
            %     TheNameFromTheriak = ASS_Names{i};
            %     WereD = ismember(TheNameFromTheriak,'_');
            %     switch sum(WereD)
            %         case 1
            %             % we delete it...
            %             Where = find(WereD);
            %             ASS_Names{i} = TheNameFromTheriak(1:Where-1);
            %         case 2
            %             % we delete the second one ...
            %             Where = find(WereD);
            %             ASS_Names{i} = TheNameFromTheriak(1:Where(2)-1);
            %         case 3
            %             keyboard
            %     end
            % end
            % ------------------------------------------------------------------- E N D
            
            % Check Theriak Names and remove EM abbreviations (ALL USERS)
            for i=1:length(ASS_Names2)
                TheNameFromTheriak = ASS_Names2{i};
                WereD = ismember(TheNameFromTheriak,'_');
                switch sum(WereD)
                    case 1
                        % we delete it...
                        Where = find(WereD);
                        ASS_Names2{i} = TheNameFromTheriak(1:Where-1);
                    case 2
                        % we delete the second one ...
                        Where = find(WereD);
                        ASS_Names2{i} = TheNameFromTheriak(1:Where(2)-1);
                    case 3
                        keyboard
                end
            end
            
            % Check Theriak Names and remove EM abbreviations
            for i=1:length(VOL_Names)
                TheNameFromTheriak = VOL_Names{i};
                WereD = ismember(TheNameFromTheriak,'_');
                switch sum(WereD)
                    case 1
                        % we delete it...
                        Where = find(WereD);
                        VOL_Names{i} = TheNameFromTheriak(1:Where-1);
                    case 2
                        % we delete the second one ...
                        Where = find(WereD);
                        VOL_Names{i} = TheNameFromTheriak(1:Where(2)-1);
                    case 3
                        keyboard
                end
            end
            
            %[WhereGrtAss] = find(ismember(ASS_Names,'GARNET'));
            [WhereGrtAss2] = find(ismember(ASS_Names2,'GARNET'));
            [WhereGrtVol] = find(ismember(VOL_Names,'GARNET'));
            
            % --------------------------------------------------------------- S T A R T
            % if length(WhereGrtAss) > 1 % more than one garnet stable
            %     Vols = VOL_VolFrac(WhereGrtVol)*100;
            %     [Val,WhereHigestVol] = max(Vols);
            %
            %     WhereGrtAss = WhereGrtAss(WhereHigestVol);
            %     WhereGrtVol = WhereGrtVol(WhereHigestVol);
            %
            %     % because they always have the same order in assemblage and composition
            %     % ...
            % end
            % ------------------------------------------------------------------- E N D
            
            if length(WhereGrtAss2) > 1 % more than one garnet stable
                Vols = VOL_VolFrac(WhereGrtVol)*100;
                [Val,WhereHigestVol] = max(Vols);
                
                WhereGrtAss2 = WhereGrtAss2(WhereHigestVol);
                WhereGrtVol = WhereGrtVol(WhereHigestVol);
                
                % because they always have the same order in assemblage and composition
                % ...
            end
            
            if isempty(WhereGrtAss2) || isempty(WhereGrtVol)
                return
                % Garnet is not stable
                
            else
                
                % --------------------------------------------------------------- S T A R T
                %     GrtResultOld(1) = VOL_VolFrac(WhereGrtVol)*100;
                %
                %     XFeGarnet = 0;
                %     XMgGarnet = 0;
                %     XCaGarnet = 0;
                %     XMnGarnet = 0;
                %
                %     WhereFe = find(ismember(WorkVariMod.Els,'FE'));
                %     if WhereFe
                %         XFeGarnet = ASS_COMP(WhereGrtAss,WhereFe);
                %     end
                %     WhereMg = find(ismember(WorkVariMod.Els,'MG'));
                %     if WhereMg
                %         XMgGarnet = ASS_COMP(WhereGrtAss,WhereMg);
                %     end
                %     WhereCa = find(ismember(WorkVariMod.Els,'CA'));
                %     if WhereCa
                %         XCaGarnet = ASS_COMP(WhereGrtAss,WhereCa);
                %     end
                %     WhereMn = find(ismember(WorkVariMod.Els,'MN'));
                %     if WhereMn
                %         XMnGarnet = ASS_COMP(WhereGrtAss,WhereMn);
                %     end
                %
                %     GrtResultOld(2) = XFeGarnet/(XMgGarnet+XCaGarnet+XFeGarnet+XMnGarnet);
                %     GrtResultOld(3) = XCaGarnet/(XMgGarnet+XCaGarnet+XFeGarnet+XMnGarnet);
                %     GrtResultOld(4) = XMgGarnet/(XMgGarnet+XCaGarnet+XFeGarnet+XMnGarnet);
                %     GrtResultOld(5) = XMnGarnet/(XMgGarnet+XCaGarnet+XFeGarnet+XMnGarnet);
                %
                %     GrtResultOld(6) = VOL_Dens(WhereGrtVol);
                % ------------------------------------------------------------------- E N D
                
                GrtResult(1) = VOL_VolFrac(WhereGrtVol)*100;
                
                XFeGarnet2 = 0;
                XMgGarnet2 = 0;
                XCaGarnet2 = 0;
                XMnGarnet2 = 0;
                
                WhereFe = find(ismember(WorkVariMod.Els,'FE'));
                if WhereFe
                    XFeGarnet2 = ASS_COMP2(WhereGrtAss2,WhereFe);
                end
                WhereMg = find(ismember(WorkVariMod.Els,'MG'));
                if WhereMg
                    XMgGarnet2 = ASS_COMP2(WhereGrtAss2,WhereMg);
                end
                WhereCa = find(ismember(WorkVariMod.Els,'CA'));
                if WhereCa
                    XCaGarnet2 = ASS_COMP2(WhereGrtAss2,WhereCa);
                end
                WhereMn = find(ismember(WorkVariMod.Els,'MN'));
                if WhereMn
                    XMnGarnet2 = ASS_COMP2(WhereGrtAss2,WhereMn);
                end
                
                GrtResult(2) = XFeGarnet2/(XMgGarnet2+XCaGarnet2+XFeGarnet2+XMnGarnet2);
                GrtResult(3) = XCaGarnet2/(XMgGarnet2+XCaGarnet2+XFeGarnet2+XMnGarnet2);
                GrtResult(4) = XMgGarnet2/(XMgGarnet2+XCaGarnet2+XFeGarnet2+XMnGarnet2);
                GrtResult(5) = XMnGarnet2/(XMgGarnet2+XCaGarnet2+XFeGarnet2+XMnGarnet2);
                
                GrtResult(6) = VOL_Dens(WhereGrtVol);
                
            end
            
        end
        
        function [Tfin,Pfin,ErrorsT,ErrorsP,ErrorsVol] = FigureOutPTError(app,Tsols,Psols,BulkSols,ResSols,GarnVolStable,VolSystFrac,FIT,PROB)
            %
            
            Tfin=[];
            Pfin=[];
            ErrorsT=[];
            ErrorsP=[];
            ErrorsVol=[];
            
            Loops = 0;
            DirectionsToTest = [1,1,1,1,1,1,1,1];
            PositiveTests =    [0,0,0,0,0,0,0,0];
            
            Tinit = round(mean(Tsols));
            Pinit = round(mean(Psols));
            
            DeltaT = PROB.INIT.TDI2;
            DeltaP = PROB.INIT.PDI2;
            
            StepsT = length([PROB.INIT.TMIN:DeltaT:PROB.INIT.TMAX]);
            StepsP = length([PROB.INIT.PMIN:DeltaP:PROB.INIT.PMAX]);
            
            % updated 1.4.1 (6.10.15)
            dT = round((PROB.INIT.TMAX-PROB.INIT.TMIN)/StepsT);
            dP = round((PROB.INIT.PMAX-PROB.INIT.PMIN)/StepsP);
            
            ResSolution = min(ResSols);
            ResError = ResSolution + PROB.INIT.RESC;
            
            ComptVol = 0;
            
            while 1
                Loops = Loops + 1;
                
                NewCoordinates = ComptDirections2Coordinates(app,DirectionsToTest,dT,dP,Tinit,Pinit);
                
                TheNewRes = [];
                TheVolPerGar = [];
                
                for i = 1:length(NewCoordinates)
                    
                    if NewCoordinates(i,1) < PROB.INIT.TMIN || NewCoordinates(i,1) > PROB.INIT.TMAX || ...
                            NewCoordinates(i,2) < PROB.INIT.PMIN || NewCoordinates(i,2) > PROB.INIT.PMAX
                    
                    % We are outside the P-T range and it makes no sense to keep
                    % working... (update 1.4.1 - 06.10.15)
                    
                    NewCoordinates(i,1) = 0;
                    NewCoordinates(i,2) = 0;
                    
                    DirectionsToTest(i) = 0;
                    end
                    
                    
                    if NewCoordinates(i,1) > 0
                        
                        D_Temp = num2str(NewCoordinates(i,1));
                        D_Press = num2str(NewCoordinates(i,2));
                        
                        InvMet.path = char(PROB.INIT.THER);
                        InvMet.dbs=char(PROB.INIT.THDB);
                        
                        InvMet.Bulk2Display = BulkSols{1};
                        InvMet.In = 'in';
                        
                        InvMet.bcomp ='THERIN';
                        
                        [TheVolPerGar,XAlm,XGro,XPyr,XSpe] = Call4Theriak(app,InvMet,D_Temp,D_Press);
                        
                        TheVolPerGar = TheVolPerGar*VolSystFrac; % volume of the entire system... (this value is the one to be used)...
                        
                        if TheVolPerGar
                            TheNewRes = sqrt(sum((FIT - [XAlm,XGro,XPyr,XSpe]).^2));
                        else
                            TheNewRes = inf;
                        end
                        
                        if TheNewRes > ResError
                            DirectionsToTest(i) = 0;
                        else
                            PositiveTests(i) = PositiveTests(i)+1;
                            
                            ComptVol = ComptVol+1;
                            ErrorsVol(ComptVol)=TheVolPerGar;
                            AllRes(i,PositiveTests(i)) = TheNewRes;
                        end
                    end
                    
                end
                
                TheExitTest = sum(DirectionsToTest);
                
                disp(['  --> Loop [',num2str(Loops),'] - (',num2str(TheExitTest),')'])
                
                if ~TheExitTest
                    disp(' ');
                    break
                end
                
                % Update DirectionsToTest...
                dT = dT + DeltaT;
                dP = dP + DeltaP;
                
            end
            
            FinalCoordinates = FinalDirections2Coordinates(app,PositiveTests,DeltaT,DeltaP,Tinit,Pinit);
            
            Tfin = Tinit;
            Pfin = Pinit;
            
            ErrorsT = FinalCoordinates(:,1);
            ErrorsP = FinalCoordinates(:,2);
        end

        function [NewCoordinates] = ComptDirections2Coordinates(app,DirectionsToTest,dT,dP,Tinit,Pinit)
            NewCoordinates(1,:) = DirectionsToTest(1) * [Tinit,Pinit+dP];
            NewCoordinates(2,:) = DirectionsToTest(2) * [Tinit+dT,Pinit+dP];
            NewCoordinates(3,:) = DirectionsToTest(3) * [Tinit+dT,Pinit];
            NewCoordinates(4,:) = DirectionsToTest(4) * [Tinit+dT,Pinit-dP];
            NewCoordinates(5,:) = DirectionsToTest(5) * [Tinit,Pinit-dP];
            NewCoordinates(6,:) = DirectionsToTest(6) * [Tinit-dT,Pinit-dP];
            NewCoordinates(7,:) = DirectionsToTest(7) * [Tinit-dT,Pinit];
            NewCoordinates(8,:) = DirectionsToTest(8) * [Tinit-dT,Pinit+dP];
        end

        
        function [FinalCoordinates] = FinalDirections2Coordinates(app,PositiveTests,DeltaT,DeltaP,Tinit,Pinit)
            FinalCoordinates(1,:) = [Tinit,Pinit+PositiveTests(1)*DeltaP];
            FinalCoordinates(2,:) = [Tinit+PositiveTests(2)*DeltaT,Pinit+PositiveTests(2)*DeltaP];
            FinalCoordinates(3,:) = [Tinit+PositiveTests(3)*DeltaT,Pinit];
            FinalCoordinates(4,:) = [Tinit+PositiveTests(4)*DeltaT,Pinit-PositiveTests(4)*DeltaP];
            FinalCoordinates(5,:) = [Tinit,Pinit-PositiveTests(5)*DeltaP];
            FinalCoordinates(6,:) = [Tinit-PositiveTests(6)*DeltaT,Pinit-PositiveTests(6)*DeltaP];
            FinalCoordinates(7,:) = [Tinit-PositiveTests(7)*DeltaT,Pinit];
            FinalCoordinates(8,:) = [Tinit-PositiveTests(8)*DeltaT,Pinit+PositiveTests(8)*DeltaP];
        end


        function  [] = PlotFigureVolChanges(app,FINA)
            
            Dt = 0.05;
            
            NbStage = size(FINA.VOLU,1);
            NbStep = 2*NbStage+1;
            
            VOLUMES = zeros(NbStage,NbStep);
            WHERE = zeros(NbStage,NbStep);
            
            Compt = 1;
            for i=1:NbStep
                if mod(i,2) % 1,3,5,7...
                    if Compt > 1
                        VOLUMES(:,i) = FINA.VOLU(:,Compt-1);
                    end
                    WHERE(:,i) = (Compt-Dt)*ones(NbStage,1);
                else
                    VOLUMES(:,i) = FINA.VOLU(:,Compt);
                    WHERE(:,i) = (Compt+Dt)*ones(NbStage,1);
                    Compt = Compt+1;
                end
            end
            
            for i=1:NbStage
                LabelsTexts{i}=['GRT-',num2str(i)];
            end
            LabelsTexts{end+1}='TOTAL GRT VOLUME';
            
            figure(2), cla
            
            plot([WHERE',WHERE(1,:)'],[VOLUMES',sum(VOLUMES,1)'])
            legend(LabelsTexts,'Location','Best')
            axis([0 NbStage+1 0 max(sum(VOLUMES,1))+3])
            drawnow
        end


        function ReadGRTMODin(app)
            %
            %
            
            % New 1.5.1 (16.02.16)
            %fid = fopen('Config_GRTMOD.txt');
            %GrtModDir = fgetl(fid);
            %TheriakDir = fgetl(fid);
            %fclose(fid);
            
            app.PROB=[];
            
            %app.PROB.INIT.THER= TheriakDir;
            app.PROB.INIT.NEXO = 0;
            
            ComptSTAGE = 0;
            
            fid = fopen([cd,'/GRTMODin.txt']);
            
            while 1
                
                TheRow = fgetl(fid);
                
                if isequal(TheRow,-1)
                    break
                end
                
                
                if length(TheRow) > 2
                    
                    if isequal(TheRow(1),'>')
                        
                        TheStr = strread(TheRow,'%s');
                        TheCode = TheStr{2};
                        
                        switch TheCode
                            
                            case 'INIT'
                                
                                while 1
                                    TheRow = fgetl(fid);
                                    
                                    if length(TheRow) > 0
                                        if isequal(TheRow(1),'*')
                                            TheStr = strread(TheRow,'%s');
                                            Theapp.VARI = TheStr{2};
                                            Theapp.VARIOK = Theapp.VARI(1:end-1);
                                            
                                            switch Theapp.VARIOK
                                                
                                                case 'THER'
                                                    app.PROB.INIT.THER= TheStr(3);
                                                
                                                case 'THDB'
                                                    app.PROB.INIT.THDB= TheStr(3);
                                                    
                                                case 'SAMP'
                                                    app.PROB.INIT.SAMP= TheRow(9:end);
                                                    
                                                case 'SYST'
                                                    app.PROB.INIT.SYST= TheStr(3:end);
                                                    
                                                case 'BULK'
                                                    app.PROB.INIT.BULK=str2num(char(TheStr(3:end)));
                                                    
                                                case 'NH2O'
                                                    app.PROB.INIT.NH2O= str2num(TheStr{3});
                                                    
                                                case 'NOFI'
                                                    app.PROB.INIT.NOFI= TheStr{3};
                                                    
                                                case 'NCFI'
                                                    app.PROB.INIT.NCFI= str2num(TheStr{3});
                                                    
                                                case 'NEXO'
                                                    app.PROB.INIT.NEXO= str2num(TheStr{3});
                                                    
                                                case 'STOL'
                                                    app.PROB.INIT.STOL= str2num(TheStr{3});
                                                    
                                                case 'SELS'
                                                    app.PROB.INIT.SELS= str2num(TheStr{3});
                                                    
                                                case 'SELP'
                                                    app.PROB.INIT.SELP= str2num(TheStr{3});
                                                    
                                                case 'TOLX'
                                                    app.PROB.INIT.TOLX= str2num(TheStr{3});
                                                    
                                                case 'TOLF'
                                                    app.PROB.INIT.TOLF= str2num(TheStr{3});
                                                    
                                                case 'DISP'
                                                    app.PROB.INIT.DISP= char(TheStr{3});
                                                    
                                                case 'RESC'
                                                    app.PROB.INIT.RESC= str2num(TheStr{3});
                                                    
                                                case {'TMIN','TMAX','PMIN','PMAX'}
                                                    eval(['app.PROB.INIT.',Theapp.VARIOK,' = str2num(char(TheStr(3)));']);
                                                    
                                                case {'TDI1','PDI1','TDI2','PDI2'}
                                                    eval(['app.PROB.INIT.',Theapp.VARIOK,' = str2num(char(TheStr(3)));']);
                                                    
                                            end
                                        else
                                            break
                                        end
                                    else
                                        break
                                    end
                                end
                                
                            case 'STAGE'
                                ComptSTAGE = ComptSTAGE+1;
                                StageRef = str2num(TheStr{3});          % not used here
                                ComptAddGuess = 0;
                                
                                if ComptSTAGE > 1
                                    app.PROB.STAGE(ComptSTAGE).MAXC = zeros(ComptSTAGE-1,1);
                                    app.PROB.STAGE(ComptSTAGE).MINC = zeros(ComptSTAGE-1,1);
                                end
                                
                                while 1
                                    TheRow = fgetl(fid);
                                    
                                    if length(TheRow) > 0
                                        if isequal(TheRow(1),'*')
                                            TheStr = strread(TheRow,'%s');
                                            Theapp.VARI = TheStr{2};
                                            Theapp.VARIOK = Theapp.VARI(1:4);
                                            
                                            %keyboard
                                            
                                            switch Theapp.VARIOK
                                                
                                                case 'COMP'
                                                    app.PROB.STAGE(ComptSTAGE).COMP= str2num(TheStr{3});
                                                    
                                                case 'OPTI'
                                                    app.PROB.STAGE(ComptSTAGE).OPTI= str2num(TheStr{3});
                                                    
                                                case 'REFI'
                                                    app.PROB.STAGE(ComptSTAGE).REFI= str2num(TheStr{3});
                                                    
                                                case 'MAPC'
                                                    app.PROB.STAGE(ComptSTAGE).MAPC= str2num(TheStr{3});
                                                    
                                                case {'CVAR'}
                                                    Theapp.VARIStr = strread(Theapp.VARI,'%s','delimiter','-');
                                                    eval(['app.PROB.',Theapp.VARIStr{2},'(',Theapp.VARIStr{3},').',Theapp.VARIStr{4}(1:end-1),' = str2num(char(TheStr(3:end)));']);
                                                    
                                                    if isequal(ComptSTAGE,1)
                                                        n = 0;
                                                    else
                                                        app.PROB.STAGE(ComptSTAGE).CVAR = app.PROB.STAGE(ComptSTAGE-1).CVAR;
                                                        n = length(app.PROB.STAGE(ComptSTAGE).CVAR.LIST);
                                                    end
                                                    
                                                    app.PROB.STAGE(ComptSTAGE).CVAR.LIST{n+1} = Theapp.VARIStr{2};
                                                    app.PROB.STAGE(ComptSTAGE).CVAR.IND(n+1) = str2num(Theapp.VARIStr{3});
                                                    
                                                case 'TRYI'
                                                    ComptAddGuess = ComptAddGuess+1;
                                                    app.PROB.STAGE(ComptSTAGE).TRYI(ComptAddGuess,:) = str2num(char(TheStr(3:end)));
                                                    
                                                case 'OPTP'
                                                    app.PROB.STAGE(ComptSTAGE).OPTP = str2num(char(TheStr(3:end)));
                                                    
                                                case 'MAXC'
                                                    app.PROB.STAGE(ComptSTAGE).MAXC = str2num(char(TheStr(3:end)));
                                                    
                                                case 'MINC'
                                                    app.PROB.STAGE(ComptSTAGE).MINC = str2num(char(TheStr(3:end)));
                                            end
                                        else
                                            break
                                        end
                                    else
                                        break
                                    end
                                end
                                
                                
                        end
                    end
                    
                    %keyboard
                end
                
            end
        end
           
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            detectInputFiles(app);
        end

        % Button pushed function: SETDIRECTORYButton
        function SETDIRECTORYButtonPushed(app, event)
            
            directoryname = uigetdir('Pick a Directory');
            
            figure(app.GRTMODAppUIFigure);
            
            if ~isequal(directoryname,0)
                cd(directoryname);
                detectInputFiles(app)
            end
            
          
        end

        % Button pushed function: RUNGRTMODButton
        function RUNGRTMODButtonPushed(app, event)
            app.WaitBar = uiprogressdlg(gcbf,'Title','Please Wait!','Indeterminate','on');
            app.WaitBar.Message = 'GRTMOD is running numbers: job starting';
            
            if isequal(app.Replacefilelast_GRTMODtxtCheckBox.Value,1)
                if exist([cd,'/last_GRTMOD.txt'])
                    delete([cd,'/last_GRTMOD.txt']);
                end
            end
            diary([cd,'/last_GRTMOD.txt']);
            try 
                Calc_GRTMOD(app);
            catch ME
                app.WaitBar.Message = 'Oups, something went wrong...';
                uiwait(warndlg({'Error:',ME.message},'GRTMOD'));
                close(app.WaitBar);
                diary('off');
            end
            close(app.WaitBar);
            diary('off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.GRTMODAppUIFigure = uifigure('Visible', 'off');
            app.GRTMODAppUIFigure.Name = 'GRTMOD App';
            app.GridLayout = uigridlayout(app.GRTMODAppUIFigure);
            app.GRTMOD20May2021contactpierrelanarigeounibechLabel = uilabel(app.GridLayout);
            app.GRTMOD20May2021contactpierrelanarigeounibechLabel.Text = 'GRTMOD 2.0 – May 2021 (contact: pierre.lanari@geo.unibe.ch)';
            app.Image_InputFile = uiimage(app.GridLayout);
            app.Image_InputFile.ImageSource = 'Valid.png';
            app.InputFileGRTMODintxtLabel = uilabel(app.GridLayout);
            app.InputFileGRTMODintxtLabel.Text = 'Input File: GRTMODin.txt';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SETDIRECTORYButton = uibutton(app.GridLayout2, 'push');
            app.SETDIRECTORYButton.ButtonPushedFcn = createCallbackFcn(app, @SETDIRECTORYButtonPushed, true);
            app.SETDIRECTORYButton.Icon = 'folder_bw.png';
            app.SETDIRECTORYButton.Text = 'SET DIRECTORY';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.RUNGRTMODButton = uibutton(app.GridLayout3, 'push');
            app.RUNGRTMODButton.ButtonPushedFcn = createCallbackFcn(app, @RUNGRTMODButtonPushed, true);
            app.RUNGRTMODButton.Icon = 'GRTMOD.png';
            app.RUNGRTMODButton.Text = 'RUN GRTMOD';
            app.Replacefilelast_GRTMODtxtCheckBox = uicheckbox(app.GridLayout);
            app.Replacefilelast_GRTMODtxtCheckBox.Text = '   Replace file last_GRTMOD.txt';
            app.Replacefilelast_GRTMODtxtCheckBox.Value = true;
            app.OptionsLabel = uilabel(app.GridLayout);
            app.OptionsLabel.Text = 'Options';
            app.GridLayout4 = uigridlayout(app.GridLayout);
            app.Image = uiimage(app.GridLayout4);
            app.Image.ImageSource = 'GRTMOD_logo.png';
            app.Image_TheriakINI = uiimage(app.GridLayout);
            app.Image_TheriakINI.ImageSource = 'Valid.png';
            app.TheriakINILabel = uilabel(app.GridLayout);
            app.TheriakINILabel.Text = 'Config File: Theriak.ini (WINDOWS)';
            % Show the figure after all components are created
            app.GRTMODAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GRTMOD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.GRTMODAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.GRTMODAppUIFigure)
        end
    end
end